classdef adapt_aep < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Label_signal_snr               matlab.ui.control.Label
        SignalSNRLabel                 matlab.ui.control.Label
        dBSPLLabel_2                   matlab.ui.control.Label
        dBSPLLabel                     matlab.ui.control.Label
        Label_tank_noise_floor         matlab.ui.control.Label
        TanknoisefloorLabel            matlab.ui.control.Label
        Label_RMS_ratio                matlab.ui.control.Label
        RMSRatioLabel                  matlab.ui.control.Label
        TempButton                     matlab.ui.control.Button
        HealthButton                   matlab.ui.control.Button
        DropDown_sex                   matlab.ui.control.DropDown
        PauseButton                    matlab.ui.control.Button
        Label_time_elapsed             matlab.ui.control.Label
        TotaltimeelapsedLabel          matlab.ui.control.Label
        peramplitudeLabel              matlab.ui.control.Label
        Label_grand_total              matlab.ui.control.Label
        Label_rejection_rate           matlab.ui.control.Label
        GrandNtrialsLabel              matlab.ui.control.Label
        Label_number_trials_presented  matlab.ui.control.Label
        Label_current_amp              matlab.ui.control.Label
        ArtRejRateLabel                matlab.ui.control.Label
        NtrialspresentedLabel          matlab.ui.control.Label
        CurrentamplitudeLabel          matlab.ui.control.Label
        DropDown_tone_burst            matlab.ui.control.DropDown
        StatusLabel                    matlab.ui.control.Label
        InitializeButton               matlab.ui.control.Button
        AnalysisLabel                  matlab.ui.control.Label
        MonitorLabel                   matlab.ui.control.Label
        DropDown_response_feature      matlab.ui.control.DropDown
        StartButton                    matlab.ui.control.Button
        CalibrateButton                matlab.ui.control.Button
        logo                           matlab.ui.control.Image
        EditField_max_trials           matlab.ui.control.NumericEditField
        EditField_frequency            matlab.ui.control.NumericEditField
        EditField_ID                   matlab.ui.control.NumericEditField
        ExperimentLabel                matlab.ui.control.Label
        MaximumtrialsLabel_2           matlab.ui.control.Label
        ResponsefeatureLabel           matlab.ui.control.Label
        HzLabel                        matlab.ui.control.Label
        StimulusLabel                  matlab.ui.control.Label
        SexLabel                       matlab.ui.control.Label
        IDLabel                        matlab.ui.control.Label
        SubjectLabel                   matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        UIAxes_model_3                 matlab.ui.control.UIAxes
        UIAxes_model_2                 matlab.ui.control.UIAxes
        UIAxes_gate                    matlab.ui.control.UIAxes
        UIAxes_tank_noise_floor        matlab.ui.control.UIAxes
        UIAxes_signal_SNR              matlab.ui.control.UIAxes
        UIAxes_slope_est               matlab.ui.control.UIAxes
        UIAxes_thresh_est              matlab.ui.control.UIAxes
        UIAxes_diff_fft                matlab.ui.control.UIAxes
        UIAxes_temp                    matlab.ui.control.UIAxes
        UIAxes_health                  matlab.ui.control.UIAxes
        UIAxes_model                   matlab.ui.control.UIAxes
        UIAxes_boot                    matlab.ui.control.UIAxes
        UIAxes_ch4                     matlab.ui.control.UIAxes
        UIAxes_ch3                     matlab.ui.control.UIAxes
        UIAxes_ch2                     matlab.ui.control.UIAxes
        UIAxes_ch1                     matlab.ui.control.UIAxes
        UIAxes_hydrophone              matlab.ui.control.UIAxes
    end


    properties (Access = public)
        ex % Experiment structure
        PauseFlag logical = false
    end
    
    methods (Access = private)
        function clearAllAxes(app)
            cla(app.UIAxes_hydrophone);
            title(app.UIAxes_hydrophone,'Hydrophone')
            cla(app.UIAxes_ch1);
            cla(app.UIAxes_ch2);
            cla(app.UIAxes_ch3);
            cla(app.UIAxes_ch4);
            cla(app.UIAxes_boot);
            cla(app.UIAxes_model);
            title(app.UIAxes_model,'Model')
            cla(app.UIAxes_model_2);
            title(app.UIAxes_model_2,'Model')
            cla(app.UIAxes_model_3);
            title(app.UIAxes_model_3,'Model')
            cla(app.UIAxes_health);
            cla(app.UIAxes_temp);
            cla(app.UIAxes_diff_fft);
            cla(app.UIAxes_thresh_est);
            cla(app.UIAxes_slope_est);
            cla(app.UIAxes_gate);
            cla(app.UIAxes_tank_noise_floor);
            cla(app.UIAxes_signal_SNR);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function start_exp(app, event)
            fprintf('\nStarting experiment...\n')
            [y, Fs] = audioread('button_press.mp3');
            sound(y, Fs)

            % Clear GUI 
            clearAllAxes(app)
            app.Label_current_amp.Text = 'X';
            app.Label_number_trials_presented.Text = 'X';
            app.Label_rejection_rate.Text = 'X';
            app.Label_grand_total.Text = 'X';
            app.Label_time_elapsed.Text = 'X';
            app.Label_RMS_ratio.Text = 'X';
            app.Label_tank_noise_floor.Text = 'X';
            app.Label_signal_snr.Text = 'X';
            
            app.CalibrateButton.Enable = 'off';
            app.InitializeButton.Enable = 'off';
            app.TempButton.Enable = 'off';
            app.HealthButton.Enable = 'off';
            app.PauseButton.Enable = 'on';

            % Rip it

            run_adapt_aep(app);
            fprintf('\nFinished testing at current frequency.')

            % Reset all controls to initial state
            app.EditField_ID.Enable = 'on';
            app.EditField_frequency.Enable = 'on';
            app.EditField_frequency.Value = [];
            app.EditField_max_trials.Enable = 'on';
            app.DropDown_response_feature.Enable = 'on';
            app.DropDown_response_feature.Value = 'Double frequency';
            app.DropDown_sex.Enable = 'on';
            app.DropDown_sex.Value = 'F';
            app.DropDown_tone_burst.Enable = 'on';
            app.DropDown_tone_burst.Value = 'Tone burst';

            app.InitializeButton.Enable = 'on';
            app.CalibrateButton.Enable = 'off';
            app.HealthButton.Enable = 'off';
            app.TempButton.Enable = 'off';
            app.StartButton.Enable = 'off';
            app.PauseButton.Enable = 'off';

            app.Label_current_amp.Text = 'X';
            app.Label_number_trials_presented.Text = 'X';
            app.Label_rejection_rate.Text = 'X';
            app.Label_grand_total.Text = 'X';
            app.Label_time_elapsed.Text = 'X';
            app.Label_RMS_ratio.Text = 'X';
            app.Label_tank_noise_floor.Text = 'X';
            app.Label_signal_snr.Text = 'X';

            app.ex = [];
            app.PauseFlag = false;
            clearAllAxes(app);

            [y, Fs] = audioread('finished_exp.mp3');
            sound(y, Fs)
        end

        % Button pushed function: CalibrateButton
        function start_calibration(app, event)
            fprintf('\nStarting calibration...\n')
            [y, Fs] = audioread('button_press.mp3');
            sound(y, Fs)

            % Check if ex has been initialized
            if isempty(app.ex) || ~isfield(app.ex, 'info')
                uialert(app.UIFigure, 'Please initialize experiment first.', 'Not Initialized');
                return;
            end

            % Create dialog asking to load existing or create new calibration
            choice = uiconfirm(app.UIFigure, ...
                'Do you want to load an existing calibration file or create a new one?', ...
                'Calibration Options', ...
                'Options', {'Load Existing', 'Create New'}, ...
                'DefaultOption', 2);

            if strcmp(choice, 'Load Existing')
                % Allow user to select calibration file
                [file, path] = uigetfile('*.mat', 'Select Calibration File');

                if file == 0
                    % User cancelled
                    return;
                end

                try
                    % Load calibration data
                    loaded = load(fullfile(path, file), 'calibration');
                    [y, Fs] = audioread('completed.mp3');
                    sound(y, Fs)
                    fprintf('\nLoaded calibration data from: %s\n', file);
                    app.ex.info.calibration = loaded.calibration;
                catch ME
                    [y, Fs] = audioread('error.mp3');
                    sound(y, Fs)
                    uialert(app.UIFigure, sprintf('Error loading calibration: %s', ME.message), 'Load Error');
                end

            else
                try
                    % Create the calibration app
                    calibration_app = calibrate_stimulus();

                    % Initialize it with ex
                    calibration_app.initializeWithEx(app.ex);

                    % Automatic button push
                    t = timer('StartDelay', 0.1, 'TimerFcn', @(varargin) calibration_app.runCalibrate());            start(t);
                    uiwait(calibration_app.UIFigure);  % now listening before button fires
                    delete(t);

                    % Get the updated ex back
                    app.ex = calibration_app.ex;

                    % Clean up
                    delete(calibration_app)
                catch ME
                    [y, Fs] = audioread('error.mp3');
                    sound(y, Fs)
                    uialert(app.UIFigure, sprintf('Error with calibration software: %s', ME.message), 'Calibration Error');
                end
            end

            app.CalibrateButton.Enable = 'off';
            app.HealthButton.Enable = 'off';
            app.StartButton.Enable = "on";
            [y, Fs] = audioread('completed.mp3');
            sound(y, Fs)
            
            fprintf('\nFinished calibration\n')
        end

        % Button pushed function: InitializeButton
        function start_init(app, event)
            fprintf('\nStarting initalization\n')
            
            % Initialize ex structure
            addpath(genpath('C:\Users\AEP\Desktop\adapt_aep\src\matlab'))
            app.ex = setup_ex();
            [y, Fs] = audioread('button_press.mp3');
            sound(y, Fs)
            
            % Check if all required fields are filled
            if isempty(app.EditField_ID.Value) || strcmp(app.DropDown_sex.Value,'X') || ...
                    isnan(app.EditField_frequency.Value) || isempty(app.DropDown_response_feature.Value) || isnan(app.EditField_max_trials.Value)

                uialert(app.UIFigure, 'Make sure all fields are filled out prior to continuing.', 'Missing Fields');
                return;
            end
            
            % Extract GUI Values
            app.ex.info.animal.subject_ID = app.EditField_ID.Value;
            app.ex.info.animal.sex = app.DropDown_sex.Value;
            app.ex.info.stimulus.frequency_hz = app.EditField_frequency.Value;
            app.ex.info.stimulus.type = app.DropDown_tone_burst.Value;
            app.ex.info.adaptive.response_feature = app.DropDown_response_feature.Value;
            app.ex.info.adaptive.max_trials = app.EditField_max_trials.Value;
            app.ex.info.animal.filename_root = sprintf('%s_%d_%gHz', ...
                app.ex.info.animal.species_name, ...
                app.ex.info.animal.subject_ID, ...
                app.ex.info.stimulus.frequency_hz);
            app.ex = setup_hardware(app.ex);

            %% Generate Stimulus Template
            app.ex = select_experiment_stim_params(app.ex);
            app.ex = make_stimulus_template(app.ex);


            % GUI Buttons
            app.InitializeButton.Enable = 'off';
            app.CalibrateButton.Enable = 'on';
            app.EditField_ID.Enable = 'off';
            app.EditField_frequency.Enable = 'off';
            app.EditField_max_trials.Enable = 'off';
            app.DropDown_response_feature.Enable = 'off';
            app.DropDown_sex.Enable = "off";
            app.DropDown_tone_burst.Enable = "off";

            [y, Fs] = audioread('completed.mp3');
            sound(y, Fs)
            
            fprintf('\nInitialization complete\n')

        end

        % Button pushed function: HealthButton
        function HealthButtonPushed(app, event)
            fprintf('\nStarting health check...\n')
            % app.HealthButton.Enable = 'off';
            [y, Fs] = audioread('button_press.mp3');
            sound(y, Fs)
            app.ex.info.health.filename_root = sprintf('%s_%d', ...
                app.ex.info.animal.species_name, ...
                app.ex.info.animal.subject_ID);
            app.ex = make_health_check_signal(app.ex);
            app.ex = check_health(app.ex, app);
            
            app.HealthButton.Enable = 'off';
            app.TempButton.Enable = 'on';
            
            [y, Fs] = audioread('completed.mp3');
            sound(y, Fs)
            fprintf('\nFinished health check\n')
        end

        % Button pushed function: TempButton
        function TempButtonPushed(app, event)
            fprintf('\nStarting temperature check...\n')
            [y, Fs] = audioread('button_press.mp3');
            sound(y, Fs)
            app.TempButton.Enable = 'off';
            app.StartButton.Enable = 'on';
            [y, Fs] = audioread('completed.mp3');
            sound(y, Fs)
            fprintf('\nFinished temperature check\n')
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            [y, Fs] = audioread('button_press.mp3');
            sound(y, Fs)
            app.PauseFlag = true;
            [y, Fs] = audioread('completed.mp3');
            sound(y, Fs)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_hydrophone = uiaxes(app.UIFigure);
            title(app.UIAxes_hydrophone, 'Hydrophone')
            ylabel(app.UIAxes_hydrophone, 'Amplitude (mV)')
            zlabel(app.UIAxes_hydrophone, 'Z')
            app.UIAxes_ch1 = uiaxes(app.UIFigure);
            title(app.UIAxes_ch1, 'Channel 1')
            zlabel(app.UIAxes_ch1, 'Z')
            app.UIAxes_ch2 = uiaxes(app.UIFigure);
            title(app.UIAxes_ch2, 'Channel 2')
            ylabel(app.UIAxes_ch2, 'Amplitude (\muV)')
            zlabel(app.UIAxes_ch2, 'Z')
            app.UIAxes_ch3 = uiaxes(app.UIFigure);
            title(app.UIAxes_ch3, 'Channel 3')
            zlabel(app.UIAxes_ch3, 'Z')
            app.UIAxes_ch4 = uiaxes(app.UIFigure);
            title(app.UIAxes_ch4, 'Channel 4')
            xlabel(app.UIAxes_ch4, 'Time (s)')
            zlabel(app.UIAxes_ch4, 'Z')
            app.UIAxes_boot = uiaxes(app.UIFigure);
            title(app.UIAxes_boot, 'Bootstrap Results')
            ylabel(app.UIAxes_boot, 'Lower CI Value')
            zlabel(app.UIAxes_boot, 'Z')
            app.UIAxes_model = uiaxes(app.UIFigure);
            title(app.UIAxes_model, 'Model')
            xlabel(app.UIAxes_model, 'Stimulus Amplitude (dB SPL)')
            ylabel(app.UIAxes_model, '2f Amplitude (\muV)')
            zlabel(app.UIAxes_model, 'Z')
            app.UIAxes_health = uiaxes(app.UIFigure);
            ylabel(app.UIAxes_health, 'Health (\muV)')
            zlabel(app.UIAxes_health, 'Z')
            app.UIAxes_temp = uiaxes(app.UIFigure);
            ylabel(app.UIAxes_temp, 'Water Temp')
            zlabel(app.UIAxes_temp, 'Z')
            app.UIAxes_diff_fft = uiaxes(app.UIFigure);
            title(app.UIAxes_diff_fft, 'Difference FFT')
            xlabel(app.UIAxes_diff_fft, 'Frequency (Hz)')
            ylabel(app.UIAxes_diff_fft, 'Amplitude (\muV)')
            zlabel(app.UIAxes_diff_fft, 'Z')
            app.UIAxes_thresh_est = uiaxes(app.UIFigure);
            title(app.UIAxes_thresh_est, 'Threshold Estimate')
            xlabel(app.UIAxes_thresh_est, 'Iteration')
            ylabel(app.UIAxes_thresh_est, 'x0')
            zlabel(app.UIAxes_thresh_est, 'Z')
            app.UIAxes_slope_est = uiaxes(app.UIFigure);
            title(app.UIAxes_slope_est, 'Slope Estimate')
            xlabel(app.UIAxes_slope_est, 'Iteration')
            ylabel(app.UIAxes_slope_est, 'm')
            zlabel(app.UIAxes_slope_est, 'Z')
            app.UIAxes_signal_SNR = uiaxes(app.UIFigure);
            title(app.UIAxes_signal_SNR, 'Signal SNR')
            ylabel(app.UIAxes_signal_SNR, 'SNR (dB)')
            zlabel(app.UIAxes_signal_SNR, 'Z')
            app.UIAxes_tank_noise_floor = uiaxes(app.UIFigure);
            title(app.UIAxes_tank_noise_floor, 'Tank Noise Floor ')
            ylabel(app.UIAxes_tank_noise_floor, 'dB SPL')
            zlabel(app.UIAxes_tank_noise_floor, 'Z')
            app.UIAxes_gate = uiaxes(app.UIFigure);
            title(app.UIAxes_gate, 'Detection Gate Type ')
            xlabel(app.UIAxes_gate, 'Gate type')
            ylabel(app.UIAxes_gate, 'Frequency')
            zlabel(app.UIAxes_gate, 'Z')
            app.UIAxes_model_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_model_2, 'Model')
            xlabel(app.UIAxes_model_2, 'Stimulus Amplitude (dB SPL)')
            ylabel(app.UIAxes_model_2, '2f Amplitude (\muV)')
            zlabel(app.UIAxes_model_2, 'Z')
            app.UIAxes_model_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_model_3, 'Model')
            xlabel(app.UIAxes_model_3, 'Stimulus Amplitude (dB SPL)')
            ylabel(app.UIAxes_model_3, '2f Amplitude (\muV)')
            zlabel(app.UIAxes_model_3, 'Z')
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '__________________________';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '__________________________';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '__________________________';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '__________________________';
            app.SubjectLabel = uilabel(app.UIFigure);
            app.SubjectLabel.Text = 'Subject';
            app.IDLabel = uilabel(app.UIFigure);
            app.IDLabel.Text = 'ID #:';
            app.SexLabel = uilabel(app.UIFigure);
            app.SexLabel.Text = 'Sex:';
            app.StimulusLabel = uilabel(app.UIFigure);
            app.StimulusLabel.Text = 'Stimulus';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.ResponsefeatureLabel = uilabel(app.UIFigure);
            app.ResponsefeatureLabel.Text = 'Response feature:';
            app.MaximumtrialsLabel_2 = uilabel(app.UIFigure);
            app.MaximumtrialsLabel_2.Text = 'Maximum trials:';
            app.ExperimentLabel = uilabel(app.UIFigure);
            app.ExperimentLabel.Text = 'Experiment';
            app.EditField_ID = uieditfield(app.UIFigure, 'numeric');
            app.EditField_ID.AllowEmpty = 'on';
            app.EditField_ID.Value = [];
            app.EditField_frequency = uieditfield(app.UIFigure, 'numeric');
            app.EditField_frequency.AllowEmpty = 'on';
            app.EditField_frequency.Value = [];
            app.EditField_max_trials = uieditfield(app.UIFigure, 'numeric');
            app.EditField_max_trials.Value = 100;
            app.logo = uiimage(app.UIFigure);
            app.logo.ImageSource = fullfile(pathToMLAPP, 'adapt_aep_logo_trans.png');
            app.CalibrateButton = uibutton(app.UIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @start_calibration, true);
            app.CalibrateButton.Enable = 'off';
            app.CalibrateButton.Text = 'Calibrate';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @start_exp, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.DropDown_response_feature = uidropdown(app.UIFigure);
            app.DropDown_response_feature.Items = {'Double frequency'};
            app.DropDown_response_feature.Value = 'Double frequency';
            app.MonitorLabel = uilabel(app.UIFigure);
            app.MonitorLabel.Text = 'Monitor';
            app.AnalysisLabel = uilabel(app.UIFigure);
            app.AnalysisLabel.Text = 'Analysis';
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @start_init, true);
            app.InitializeButton.Text = 'Initialize';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            app.DropDown_tone_burst = uidropdown(app.UIFigure);
            app.DropDown_tone_burst.Items = {'Tone burst'};
            app.DropDown_tone_burst.Value = 'Tone burst';
            app.CurrentamplitudeLabel = uilabel(app.UIFigure);
            app.CurrentamplitudeLabel.Text = 'Current amplitude: ';
            app.NtrialspresentedLabel = uilabel(app.UIFigure);
            app.NtrialspresentedLabel.Text = 'N trials presented:';
            app.ArtRejRateLabel = uilabel(app.UIFigure);
            app.ArtRejRateLabel.Text = 'Art Rej. Rate:';
            app.Label_current_amp = uilabel(app.UIFigure);
            app.Label_current_amp.Text = 'X';
            app.Label_number_trials_presented = uilabel(app.UIFigure);
            app.Label_number_trials_presented.Text = 'X';
            app.GrandNtrialsLabel = uilabel(app.UIFigure);
            app.GrandNtrialsLabel.Text = 'Grand N trials: ';
            app.Label_rejection_rate = uilabel(app.UIFigure);
            app.Label_rejection_rate.Text = 'X';
            app.Label_grand_total = uilabel(app.UIFigure);
            app.Label_grand_total.Text = 'X';
            app.peramplitudeLabel = uilabel(app.UIFigure);
            app.peramplitudeLabel.Text = 'per amplitude';
            app.TotaltimeelapsedLabel = uilabel(app.UIFigure);
            app.TotaltimeelapsedLabel.Text = 'Total time elapsed:';
            app.Label_time_elapsed = uilabel(app.UIFigure);
            app.Label_time_elapsed.Text = 'X';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Enable = 'off';
            app.PauseButton.Text = 'Pause';
            app.DropDown_sex = uidropdown(app.UIFigure);
            app.DropDown_sex.Items = {'X', 'F', 'M'};
            app.DropDown_sex.Value = 'X';
            app.HealthButton = uibutton(app.UIFigure, 'push');
            app.HealthButton.ButtonPushedFcn = createCallbackFcn(app, @HealthButtonPushed, true);
            app.HealthButton.Enable = 'off';
            app.HealthButton.Text = 'Health';
            app.TempButton = uibutton(app.UIFigure, 'push');
            app.TempButton.ButtonPushedFcn = createCallbackFcn(app, @TempButtonPushed, true);
            app.TempButton.Enable = 'off';
            app.TempButton.Text = 'Temp';
            app.RMSRatioLabel = uilabel(app.UIFigure);
            app.RMSRatioLabel.Text = 'RMS Ratio :';
            app.Label_RMS_ratio = uilabel(app.UIFigure);
            app.Label_RMS_ratio.Text = 'X';
            app.TanknoisefloorLabel = uilabel(app.UIFigure);
            app.TanknoisefloorLabel.Text = 'Tank noise floor:';
            app.Label_tank_noise_floor = uilabel(app.UIFigure);
            app.Label_tank_noise_floor.Text = 'X';
            app.dBSPLLabel = uilabel(app.UIFigure);
            app.dBSPLLabel.Text = 'dB SPL';
            app.dBSPLLabel_2 = uilabel(app.UIFigure);
            app.dBSPLLabel_2.Text = 'dB SPL';
            app.SignalSNRLabel = uilabel(app.UIFigure);
            app.SignalSNRLabel.Text = 'Signal SNR:';
            app.Label_signal_snr = uilabel(app.UIFigure);
            app.Label_signal_snr.Text = 'X';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adapt_aep

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end